classdef CT_Scanner_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        ResultsPanel          matlab.ui.container.Panel
        NextButton            matlab.ui.control.Button
        SI_Axes               matlab.ui.control.UIAxes
        difference_display    matlab.ui.control.UIAxes
        CTScannerPanel        matlab.ui.container.Panel
        uipanel5              matlab.ui.container.Panel
        angular_inc           matlab.ui.control.NumericEditField
        Label_3               matlab.ui.control.Label
        n_transducers         matlab.ui.control.NumericEditField
        Label_2               matlab.ui.control.Label
        phantom_size          matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        SizeofPhantompxLabel  matlab.ui.control.Label
        start_analyze         matlab.ui.control.Button
        reconstruct           matlab.ui.control.Button
        reset                 matlab.ui.control.Button
        get_phantom           matlab.ui.control.Button
        probe_type            matlab.ui.control.DropDown
        text3                 matlab.ui.control.Label
        text2                 matlab.ui.control.Label
        text1                 matlab.ui.control.Label
        axes_new              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CT_Scanner_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            movegui(app.figure1, 'onscreen');
            axes(handles.axes_new); hold on;
            handles.next_plot = int16(0);
            handles.rAlign = 200;
            handles.cAlign = 220;
            handles.original_line = gobjects(1);
            handles.new_line = gobjects(1);
            handles.legend = gobjects(1);
            handles.image_diff = gobjects(1);
            handles.newcolors = {'k', 'r'}; 
            guidata(hObject, handles);
        end

        % Button pushed function: get_phantom
        function generate_phantom(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU> 
            img = phantom('Modified Shepp-Logan', app.phantom_size.Value); 
            handles.old_input = img;
            imshow(img);
            app.axes_new.Title.String = "Generated Phantom";
            set(hObject, 'Enable', 'off');
            set(app.reset, 'Enable', 'on');
            set(app.n_transducers, 'Enable', 'on');
            set(app.angular_inc, 'Enable', 'on');
            set(app.probe_type, 'Enable', 'on');
            set(app.reconstruct, 'Enable', 'on');
            guidata(hObject, handles);
        end

        % Button pushed function: reset
        function reset_App(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            close(gcbf);
            CT_Scanner_;
        end

        % Button pushed function: reconstruct
        function reconstruct_image(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            reconstructed_img = Shepp_Logan(handles.old_input, app.phantom_size.Value, app.n_transducers.Value, app.angular_inc.Value, app.probe_type.Value); %#ok<ST2NM> 
            handles.img_for_analysis = reconstructed_img;
            axes(handles.axes_new); hold on;
            app.axes_new.Title.String = "Reconstructed Phantom";
            imshow(reconstructed_img);
            set(app.start_analyze, 'Enable', 'on');
            guidata(hObject, handles);
        end

        % Button pushed function: start_analyze
        function analyze_image(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(app.SI_Axes, 'Visible', 'off');
            delete(handles.original_line);
            delete(handles.new_line);
            delete(handles.legend);
            delete(handles.image_diff);
            %app.figure1.Position = [520 345 760 580];
            set(app.figure1, "Position", [520 345 760 580])
            handles.ImageSubt = handles.img_for_analysis - handles.old_input;
            app.difference_display.Title.String = "Reconstructed - Original Generated Phantom";
            handles.image_diff = imshow(handles.ImageSubt, "Parent", app.difference_display);
            set(hObject, 'Enable', 'off');
            set(app.ResultsPanel, 'Visible', 'on');
            set(app.NextButton, 'Tooltip', 'Signal Intensity by Row')
            handles.next_plot = 1;
            guidata(hObject, handles);
        end

        % Button pushed function: NextButton
        function next(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            if handles.next_plot == 0
                delete(handles.original_line);
                delete(handles.new_line);
                delete(handles.legend);
                handles.next_plot = 1;
                app.difference_display.Title.String = "Reconstructed - Original Generated Phantom";
                set(app.SI_Axes, 'Visible', 'off');
                handles.image_diff = imshow(handles.ImageSubt, "Parent", app.difference_display);
                set(app.NextButton, 'Tooltip', 'Signal Intensity by Row')
            elseif handles.next_plot == 1
                handles.next_plot = 2;
                set(app.SI_Axes, 'Visible', 'on');
                set(app.NextButton, 'Tooltip', 'Signal Intensity by Column')
                delete(handles.image_diff);
                app.difference_display.Title.String = "";
                axes(app.SI_Axes); hold on;
                handles.original_line = plot(handles.old_input(handles.rAlign,:)); 
                handles.new_line = plot(handles.img_for_analysis(handles.rAlign,:));
                colororder(handles.newcolors);
                title('Signal Intensity by Row');
                handles.legend = legend('Phantom','Reconstruction');
                hold off;
            elseif handles.next_plot == 2
                set(app.NextButton, 'Tooltip', 'Reconstructed Image - Original Generated Phantom')
                axes(app.SI_Axes); hold on;
                handles.next_plot = 0;
                delete(handles.original_line)
                delete(handles.new_line)
                handles.original_line = plot(handles.old_input(handles.cAlign,:)); 
                handles.new_line = plot(handles.img_for_analysis(handles.cAlign,:));
                colororder(handles.newcolors);
                title('Signal Intensity by Column');
                handles.legend = legend('Phantom','Reconstruction');
                hold off;
            end
            guidata(hObject, handles);
        end

        % Value changed function: phantom_size
        function enable_gen(app, event)
            set(app.get_phantom, 'Enable', 'on');
            set(app.ResultsPanel, 'Visible', 'off');
            set(app.reconstruct, 'Enable', 'off');
            set(app.start_analyze, 'Enable', 'off');
            set(app.figure1, "Position", [520 345 380 580]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'CT_Scanner';
            app.figure1.Tag = 'figure1';
            app.CTScannerPanel = uipanel(app.figure1);
            app.CTScannerPanel.Title = 'CT Scanner';
            app.axes_new = uiaxes(app.CTScannerPanel);
            title(app.axes_new, {''; ''})
            app.axes_new.XLim = [0.5 420.5];
            app.axes_new.YLim = [0.5 420.5];
            app.axes_new.YDir = 'reverse';
            app.axes_new.CLim = [0 1];
            app.axes_new.Visible = 'off';
            app.axes_new.Tag = 'axes_new';
            app.uipanel5 = uipanel(app.CTScannerPanel);
            app.uipanel5.Title = 'Reconstruction & Analyzation';
            app.uipanel5.Tag = 'uipanel5';
            app.text1 = uilabel(app.uipanel5);
            app.text1.Tag = 'text1';
            app.text1.Text = 'No. of transducers';
            app.text2 = uilabel(app.uipanel5);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Angular increment';
            app.text3 = uilabel(app.uipanel5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Transducer Type';
            app.probe_type = uidropdown(app.uipanel5);
            app.probe_type.Items = {'Arc', 'Linear'};
            app.probe_type.Tag = 'probe_type';
            app.probe_type.Enable = 'off';
            app.probe_type.Value = 'Arc';
            app.get_phantom = uibutton(app.uipanel5, 'push');
            app.get_phantom.ButtonPushedFcn = createCallbackFcn(app, @generate_phantom, true);
            app.get_phantom.Tag = 'generate_phantom';
            app.get_phantom.Tooltip = {'Generate a phantom with the given size'};
            app.get_phantom.Text = {'Generate Phantom'; ''};
            app.reset = uibutton(app.uipanel5, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_App, true);
            app.reset.Tag = 'reset';
            app.reset.Text = 'Reset';
            app.reconstruct = uibutton(app.uipanel5, 'push');
            app.reconstruct.ButtonPushedFcn = createCallbackFcn(app, @reconstruct_image, true);
            app.reconstruct.Tag = 'set_paras';
            app.reconstruct.Enable = 'off';
            app.reconstruct.Text = 'Reconstruct';
            app.start_analyze = uibutton(app.uipanel5, 'push');
            app.start_analyze.ButtonPushedFcn = createCallbackFcn(app, @analyze_image, true);
            app.start_analyze.Tag = 'start_analyze';
            app.start_analyze.Enable = 'off';
            app.start_analyze.Text = 'Analyze Phantom';
            app.SizeofPhantompxLabel = uilabel(app.uipanel5);
            app.SizeofPhantompxLabel.Text = 'Size of Phantom (px)';
            app.Label = uilabel(app.uipanel5);
            app.Label.Text = '';
            app.phantom_size = uieditfield(app.uipanel5, 'numeric');
            app.phantom_size.Limits = [300 600];
            app.phantom_size.RoundFractionalValues = 'on';
            app.phantom_size.ValueChangedFcn = createCallbackFcn(app, @enable_gen, true);
            app.phantom_size.Value = 400;
            app.Label_2 = uilabel(app.uipanel5);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '';
            app.n_transducers = uieditfield(app.uipanel5, 'numeric');
            app.n_transducers.Limits = [1 100];
            app.n_transducers.RoundFractionalValues = 'on';
            app.n_transducers.Enable = 'off';
            app.n_transducers.Tooltip = {'Number of detectors within the virtual CT Scanner'};
            app.n_transducers.Value = 1;
            app.Label_3 = uilabel(app.uipanel5);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '';
            app.angular_inc = uieditfield(app.uipanel5, 'numeric');
            app.angular_inc.Limits = [1 100];
            app.angular_inc.RoundFractionalValues = 'on';
            app.angular_inc.Enable = 'off';
            app.angular_inc.Tooltip = {'The amount the scanner angle increases per step of the scanner'};
            app.angular_inc.Value = 1;
            app.ResultsPanel = uipanel(app.figure1);
            app.ResultsPanel.Title = 'Results';
            app.ResultsPanel.Visible = 'off';
            app.difference_display = uiaxes(app.ResultsPanel);
            title(app.difference_display, {''; ''})
            app.difference_display.XLim = [0.5 420.5];
            app.difference_display.YLim = [0.5 420.5];
            app.difference_display.YDir = 'reverse';
            app.difference_display.CLim = [0 1];
            app.difference_display.Visible = 'off';
            app.difference_display.Tag = 'axes_new';
            app.SI_Axes = uiaxes(app.ResultsPanel);
            title(app.SI_Axes, 'Title')
            xlabel(app.SI_Axes, 'X')
            ylabel(app.SI_Axes, 'Y')
            zlabel(app.SI_Axes, 'Z')
            app.NextButton = uibutton(app.ResultsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @next, true);
            app.NextButton.Tooltip = {''};
            app.NextButton.Text = {'Next '; ''};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CT_Scanner_(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)CT_Scanner_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end